classdef ReportGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ReportGenerateButton  matlab.ui.control.Button
        LoadButton            matlab.ui.control.Button
        ImageAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pic_path; % Description
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            
            imagesc(app.ImageAxes,im);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.pic_path = [p f];
               updateimage(app, app.pic_path);
            end
        end

        % Button pushed function: ReportGenerateButton
        function ReportGenerateButtonPushed(app, event)
            %% レポート生成
            GenRep(pwd , app.pic_path);

            msgbox('Finished');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '1. Load Image';
            app.ReportGenerateButton = uibutton(app.UIFigure, 'push');
            app.ReportGenerateButton.ButtonPushedFcn = createCallbackFcn(app, @ReportGenerateButtonPushed, true);
            app.ReportGenerateButton.Text = '2. Report Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReportGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end